classdef computer_vision < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CompositeImageCreatorUIFigure  matlab.ui.Figure
        btnBrowse                      matlab.ui.control.Button
        btnBrowseLabel                 matlab.ui.control.Label
        PleaseenterthenumberofpixelstocreateEditFieldLabel  matlab.ui.control.Label
        numPixels                      matlab.ui.control.NumericEditField
        PleaseenterthenumberoftilestocreateEditFieldLabel  matlab.ui.control.Label
        numTiles                       matlab.ui.control.NumericEditField
        compositeImagePanel            matlab.ui.container.Panel
        btnSubmit                      matlab.ui.control.Button
        PleaseselectthetypeofcompositionbuilderDropDownLabel  matlab.ui.control.Label
        compositionBuilder             matlab.ui.control.DropDown
    end


    properties (Access = private)
        targetImage % our target image
        tImageH % our target image height
        tImageW % our target image width
        srcDirectory % our composite image library
        srcImageSet % our composite src image set
        rgbAvgValues % RGB Average of composite library
        segmentSize % global segment size
        compositeImage % final image
    end

    methods (Access = private)
    
        function updatePixelValue(app)
            im = app.targetImage;
            
            im = imbinarize(im);
            
            [rows, columns, depth] = size(im);
            numberOfPixels = rows*columns;
            
            app.numPixels.Value = numberOfPixels;
            app.tImageH = rows;
            app.tImageW = columns;
        end
        
        
        function updateSrcDirectory(app)
            app.srcDirectory = uigetdir;
        end
        
        
        function setSrcImagesToMatrix(app)
            app.srcImageSet = imageSet(fullfile(app.srcDirectory));
            
            buildCompositeLibraryRGBValues(app);
        end
        
        
        function segmentTargetImage(app)
            
            app.segmentSize = [uint16((app.tImageH/app.numTiles.Value)) uint16((app.tImageW/app.numTiles.Value))];
            
            fun = @(block_struct) block_struct.data(:,:,[2 1 3]);
            
            app.compositeImage = blockproc(app.targetImage, app.segmentSize, fun);
            
        end
        
        
        function block = buildComposite(app, imblock)
            
            blockValue = mean(imblock);
    
%             match = findMatch(app, blockValue);
            
            block = imblock(:,:,[2 1 3]);
            
        end
        
        
        function buildCompositeLibraryRGBValues(app)

            % https://www.mathworks.com/matlabcentral/answers/160076-how-to-read-multiple-images-and-calculate-mean
            meanEachImage = arrayfun(@(x) mean(reshape(app.srcImageSet.read(x),[],3)), (1:app.srcImageSet.Count)','UniformOutput',false);
            
            meanImages = [];
            
            for i = 1 : size(meanEachImage)
                meanImages = [meanImages;mean(cell2mat(meanEachImage(i)))];
            end
            
            app.rgbAvgValues = meanImages;
            
        end
        
        
        function match = findMatch(app, blockValue)
            
            % Find index of the best matching rgb value
            [c index] = min(abs(app.rgbAvgValues-blockValue));
        
            % Works in console not here
%             val = app.srcImageSet.read(index);
%             match = imresize(val, app.segmentSize);
            
            % Works in console not here
%             location = char(app.srcImageSet.ImageLocation(index));
%             val = imread(location);
            
            match = imresize(val, app.segmentSize);
            
        end
        
    end


    methods (Access = private)

        % Button pushed function: btnBrowse
        function btnBrowsePushed(app, event)
            [filename, pathname] = uigetfile( ...
                {'*.jpg';'*.png';'*.bmp';'*.*'}, ...
                'Pick an file');
            
            app.targetImage = imread(strcat(pathname, filename));
            
            updatePixelValue(app);
            
        end

        % Button pushed function: btnSubmit
        function btnSubmitPushed(app, event)
            updateSrcDirectory(app);
            
            setSrcImagesToMatrix(app);
            
            segmentTargetImage(app);
            
            % update later to be shown in composite panel
            imshow(app.compositeImage);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CompositeImageCreatorUIFigure = uifigure;
            app.CompositeImageCreatorUIFigure.Name = 'Composite Image Creator';
            app.btnBrowse = uibutton(app.CompositeImageCreatorUIFigure, 'push');
            app.btnBrowse.ButtonPushedFcn = createCallbackFcn(app, @btnBrowsePushed, true);
            app.btnBrowse.Text = 'Browse...';
            app.btnBrowseLabel = uilabel(app.CompositeImageCreatorUIFigure);
            app.btnBrowseLabel.Text = 'Please select your target image:';
            app.PleaseenterthenumberofpixelstocreateEditFieldLabel = uilabel(app.CompositeImageCreatorUIFigure);
            app.PleaseenterthenumberofpixelstocreateEditFieldLabel.Text = 'Please enter the number of pixels to create';
            app.numPixels = uieditfield(app.CompositeImageCreatorUIFigure, 'numeric');
            app.numPixels.Limits = [1 Inf];
            app.numPixels.ValueDisplayFormat = '%.0f';
            app.numPixels.Editable = 'off';
            app.numPixels.Value = 1;
            app.PleaseenterthenumberoftilestocreateEditFieldLabel = uilabel(app.CompositeImageCreatorUIFigure);
            app.PleaseenterthenumberoftilestocreateEditFieldLabel.Text = 'Please enter the number of tiles to create';
            app.numTiles = uieditfield(app.CompositeImageCreatorUIFigure, 'numeric');
            app.numTiles.Limits = [1 Inf];
            app.numTiles.ValueDisplayFormat = '%.0f';
            app.numTiles.Value = 1;
            app.compositeImagePanel = uipanel(app.CompositeImageCreatorUIFigure);
            app.compositeImagePanel.Title = 'Composite Image';
            app.btnSubmit = uibutton(app.CompositeImageCreatorUIFigure, 'push');
            app.btnSubmit.ButtonPushedFcn = createCallbackFcn(app, @btnSubmitPushed, true);
            app.btnSubmit.Text = 'Submit';
            app.PleaseselectthetypeofcompositionbuilderDropDownLabel = uilabel(app.CompositeImageCreatorUIFigure);
            app.PleaseselectthetypeofcompositionbuilderDropDownLabel.Text = 'Please select the type of composition builder';
            app.compositionBuilder = uidropdown(app.CompositeImageCreatorUIFigure);
            app.compositionBuilder.Items = {'Avg. RGB Values', 'Option 2'};
            app.compositionBuilder.Value = 'Avg. RGB Values';
        end
    end

    methods (Access = public)

        % Construct app
        function app = computer_vision

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CompositeImageCreatorUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CompositeImageCreatorUIFigure)
        end
    end
end